classdef Data_Visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DataVisualizationLabel  matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        LatLongTab              matlab.ui.container.Tab
        UIAxes_LL               matlab.ui.control.UIAxes
        timeSliderLabel_2       matlab.ui.control.Label
        timeSlider_LL           matlab.ui.control.Slider
        RangeTimeTab            matlab.ui.container.Tab
        timeSliderLabel         matlab.ui.control.Label
        timeSlider_RT           matlab.ui.control.Slider
        UIAxes_RT               matlab.ui.control.UIAxes
        LoadDataButton          matlab.ui.control.Button
        SelectObjectsButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        T_combined % Radar Data combined with Truth Data
        T_appobject % Combined object data
        p_truth_LL % Global plot value for initial point (truth LL)
        p_radar_LL % Global plot value for initial point (radar LL)
        p_truth_RT % Global plot value for initial point (truth RT)
        p_radar_RT % Global plot value for initial point (radar RT)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            
            [~, ~, app.T_combined.value] = import_data_project ();
            
        end

        % Button pushed function: SelectObjectsButton
        function SelectObjectsButtonPushed(app, event)
            
            % Load object data
            app.T_appobject.value = select_object (app.T_combined.value);
            
            % Clear figures
            hold(app.UIAxes_LL,'off')
            hold(app.UIAxes_RT,'off')
            
            % Plotting truth data
            T_truth = app.T_appobject.value;
            T_truth = T_truth(strcmp(T_truth.data_type, 'gps'),:);
            
            %Defining variables
            unique_object = unique(T_truth.object);
            app.p_truth_LL.value = cell(length(unique_object),3);
            app.p_truth_RT.value = cell(length(unique_object),3);
            
            for i = 1:1:length(unique_object)
                
                T_object = T_truth(strcmp(T_truth.object, unique_object(i)),:);
                
                %Lat-Long
                plot(app.UIAxes_LL, T_object.long, T_object.lat, 'black.-')
                hold(app.UIAxes_LL,'on')
                
                %plot and label first point
                text(app.UIAxes_LL, T_object.long(1), T_object.lat(1), T_object.object(i))
                app.p_truth_LL.value{i,1} = plot(app.UIAxes_LL, T_object.long(1), T_object.lat(1), 'black^', 'MarkerSize', 12);
                app.p_truth_LL.value{i,2} = unique_object(i); %Store unique object values in column 2
                app.p_truth_LL.value{i,3} = find(strcmp(app.T_appobject.value.object, unique_object(i)) & ...
                                                 strcmp(app.T_appobject.value.data_type, 'gps'), 1, 'last');
                delete(app.p_truth_LL.value{i,1})
                
                %Range-time
                plot(app.UIAxes_RT, T_object.time, T_object.range, 'black.-')
                hold(app.UIAxes_RT,'on')
                
                %plot and label first point
                text(app.UIAxes_RT, T_object.time(1), T_object.range(1), T_object.object(i))
                app.p_truth_RT.value{i,1} = plot(app.UIAxes_RT, T_object.time(1), T_object.range(1), 'black^', 'MarkerSize', 12);
                app.p_truth_RT.value{i,2} = unique_object(i); %Store unique object values in column 2
                app.p_truth_RT.value{i,3} = find(strcmp(app.T_appobject.value.object, unique_object(i)) & ...
                                                 strcmp(app.T_appobject.value.data_type, 'gps'), 1, 'last');
                delete(app.p_truth_RT.value{i,1})
            end
            
            % Plotting radar data
            T_radar = app.T_appobject.value;
            T_radar = T_radar(strcmp(T_radar.data_type, 'radar'),:);
            
            %Defining Variables
            unique_ctsl = unique(T_radar.ctsl);
            app.p_radar_LL.value = cell(length(unique_ctsl),3);
            app.p_radar_RT.value = cell(length(unique_ctsl),3);
            
            for i = 1:1:length(unique_ctsl)
                
                T_ctsl = T_radar(T_radar.ctsl == unique_ctsl(i),:);
                T_ctsl = sortrows(T_ctsl, 'time');
                
                %Lat-Long
                plot(app.UIAxes_LL, T_ctsl.long, T_ctsl.lat, '.-')
                hold(app.UIAxes_LL,'on')
                
                %plot and label first point
                text(app.UIAxes_LL, T_ctsl.long(1), T_ctsl.lat(1), string(unique_ctsl(i)))
                app.p_radar_LL.value{i,1} = plot(app.UIAxes_LL, T_ctsl.long(1), T_ctsl.lat(1), 'blacksq', 'MarkerSize', 12);
                app.p_radar_LL.value{i,2} = unique_ctsl(i);
                app.p_radar_LL.value{i,3} = find(app.T_appobject.value.ctsl == unique_ctsl(i) & ...
                                                 strcmp(app.T_appobject.value.data_type, 'radar'), 1, 'last');
                delete(app.p_radar_LL.value{i,1})
                
                %Range-time
                plot(app.UIAxes_RT, T_ctsl.time, T_ctsl.range, '.-')
                hold(app.UIAxes_RT,'on')
                
                %plot and label first point
                text(app.UIAxes_RT, T_ctsl.time(1), T_ctsl.range(1), string(unique_ctsl(i)))
                app.p_radar_RT.value{i,1} = plot(app.UIAxes_RT, T_ctsl.time(1), T_ctsl.range(1), 'blacksq', 'MarkerSize', 12);
                app.p_radar_RT.value{i,2} = unique_ctsl(i);
                app.p_radar_RT.value{i,3} = find(app.T_appobject.value.ctsl == unique_ctsl(i) & ...
                                                 strcmp(app.T_appobject.value.data_type, 'radar'), 1, 'last');
                delete(app.p_radar_RT.value{i,1})
            end
            
            %Setting up slider options
            T = app.T_appobject.value;
            app.timeSlider_LL.Limits = [0 height(T)];
            app.timeSlider_RT.Limits = [0 height(T)];
            
        end

        % Value changing function: timeSlider_LL
        function timeSlider_LLValueChanging(app, event)

            changingValue = round(event.Value);
            
            T = app.T_appobject.value;
            
            if changingValue == 0 
                %Reset markers
                for i = 1:size(app.p_truth_LL.value)
                    delete(app.p_truth_LL.value{i,1})
                end
                
                for i = 1:size(app.p_radar_LL.value)
                    delete(app.p_radar_LL.value{i,1})
                end
            else
                if strcmp(T.data_type(changingValue), 'gps')
                    for i = 1:size(app.p_truth_LL.value)
                        if strcmp(T.object(changingValue), app.p_truth_LL.value{i,2})
                            delete(app.p_truth_LL.value{i,1}) % Deletes point in plot
                            app.p_truth_LL.value{i,1} = plot(app.UIAxes_LL, T.long(changingValue), T.lat(changingValue), ...
                                                             'black^', 'MarkerSize', 12); %plots new point
                        if changingValue ==  app.p_truth_LL.value{i,3}
                            delete(app.p_truth_LL.value{i,1}) % Deletes point in plot
                        end
                        end
                    end
                else
                    for i = 1:size(app.p_radar_LL.value)
                        if T.ctsl(changingValue) == app.p_radar_LL.value{i,2}
                            delete(app.p_radar_LL.value{i,1}) % Deletes point in plot
                            app.p_radar_LL.value{i,1} = plot(app.UIAxes_LL, T.long(changingValue), T.lat(changingValue), ...
                                                             'blacksq', 'MarkerSize', 12); %plots new point
                        if changingValue ==  app.p_radar_LL.value{i,3}
                            delete(app.p_radar_LL.value{i,1}) % Deletes point in plot
                        end
                        end
                    end
                end
            end
            
        end

        % Value changing function: timeSlider_RT
        function timeSlider_RTValueChanging(app, event)
            
            changingValue = round(event.Value);
            
            T = app.T_appobject.value;
            
            if changingValue == 0 
                %Reset markers
                for i = 1:size(app.p_truth_RT.value)
                    delete(app.p_truth_RT.value{i,1})
                end
                
                for i = 1:size(app.p_radar_RT.value)
                    delete(app.p_radar_RT.value{i,1})
                end
            else
                if strcmp(T.data_type(changingValue), 'gps')
                    for i = 1:size(app.p_truth_RT.value)
                        if strcmp(T.object(changingValue), app.p_truth_RT.value{i,2})
                            delete(app.p_truth_RT.value{i,1}) % Deletes point in plot
                            app.p_truth_RT.value{i,1} = plot(app.UIAxes_RT, T.time(changingValue), T.range(changingValue), ...
                                                             'black^', 'MarkerSize', 12); %plots new point
                        if changingValue ==  app.p_truth_RT.value{i,3}
                            delete(app.p_truth_RT.value{i,1}) % Deletes point in plot
                        end
                        end
                    end
                else
                    for i = 1:size(app.p_radar_RT.value)
                        if T.ctsl(changingValue) == app.p_radar_RT.value{i,2}
                            delete(app.p_radar_RT.value{i,1}) % Deletes point in plot
                            app.p_radar_RT.value{i,1} = plot(app.UIAxes_RT, T.time(changingValue), T.range(changingValue), ...
                                                             'blacksq', 'MarkerSize', 12); %plots new point
                        if changingValue ==  app.p_radar_RT.value{i,3}
                            delete(app.p_radar_RT.value{i,1}) % Deletes point in plot
                        end
                        end
                    end
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DataVisualizationLabel = uilabel(app.UIFigure);
            app.DataVisualizationLabel.Text = 'Data Visualization';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LatLongTab = uitab(app.TabGroup);
            app.LatLongTab.Title = 'Lat-Long';
            app.UIAxes_LL = uiaxes(app.LatLongTab);
            title(app.UIAxes_LL, 'Latitude vs. Longitude')
            xlabel(app.UIAxes_LL, 'Longitude (Deg)')
            ylabel(app.UIAxes_LL, 'Latitude (Deg)')
            app.timeSliderLabel_2 = uilabel(app.LatLongTab);
            app.timeSliderLabel_2.Text = 'time';
            app.timeSlider_LL = uislider(app.LatLongTab);
            app.timeSlider_LL.Limits = [0 10000];
            app.timeSlider_LL.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29];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
            app.timeSlider_LL.MajorTickLabels = {''};
            app.timeSlider_LL.ValueChangingFcn = createCallbackFcn(app, @timeSlider_LLValueChanging, true);
            app.timeSlider_LL.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29];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
            app.RangeTimeTab = uitab(app.TabGroup);
            app.RangeTimeTab.Title = 'Range-Time';
            app.timeSliderLabel = uilabel(app.RangeTimeTab);
            app.timeSliderLabel.Text = 'time';
            app.timeSlider_RT = uislider(app.RangeTimeTab);
            app.timeSlider_RT.Limits = [0 10000];
            app.timeSlider_RT.MajorTicks = [];
            app.timeSlider_RT.ValueChangingFcn = createCallbackFcn(app, @timeSlider_RTValueChanging, true);
            app.timeSlider_RT.MinorTicks = [];
            app.UIAxes_RT = uiaxes(app.RangeTimeTab);
            title(app.UIAxes_RT, 'Latitude vs. Longitude')
            xlabel(app.UIAxes_RT, 'Time (sec)')
            ylabel(app.UIAxes_RT, 'Range (m)')
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SelectObjectsButton = uibutton(app.UIFigure, 'push');
            app.SelectObjectsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectObjectsButtonPushed, true);
            app.SelectObjectsButton.Text = 'Select Objects';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_Visualization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end